classdef annotator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        RightPanel                 matlab.ui.container.Panel
        PlayButton                 matlab.ui.control.Button
        Slider                     matlab.ui.control.RangeSlider
        video                      matlab.ui.control.UIAxes
        CenterPanel                matlab.ui.container.Panel
        FrequencyfsEditField       matlab.ui.control.NumericEditField
        FrequencyfsEditFieldLabel  matlab.ui.control.Label
        ConffirmChannelsButton     matlab.ui.control.Button
        LabelListBoxLabel_2        matlab.ui.control.Label
        Tree                       matlab.ui.container.CheckBoxTree
        DecreaseAmpButton          matlab.ui.control.Button
        IncreaseAmpButton          matlab.ui.control.Button
        seeg_wave                  matlab.ui.control.UIAxes
        LeftPanel                  matlab.ui.container.Panel
        LogLabel                   matlab.ui.control.Label
        ConfirmButton              matlab.ui.control.Button
        SessionEditField           matlab.ui.control.NumericEditField
        SessionEditFieldLabel      matlab.ui.control.Label
        SubjectEditField           matlab.ui.control.NumericEditField
        SubjectEditFieldLabel      matlab.ui.control.Label
        NextButton                 matlab.ui.control.Button
        PreviousButton             matlab.ui.control.Button
        LabelListBox               matlab.ui.control.ListBox
        LabelListBoxLabel          matlab.ui.control.Label
    end

    
    properties (Access = private)
        numCh
        totalDuration
        epochDuration
        numEpoch
        currentEpoch
        
        seegData      % seeg data
        channelIDs    % selected channels
        channelConfig
        channelNames
        chName2ID

        videoFile     % Video file path
        videoReader   % VideoReader object
        isPlaying     % Flag to control video playback

        labels        % annotations
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            return
        end

        % Button pushed function: ConfirmButton
        function load_data(app, event)
            tsvFile = strjoin(["./sub-", num2str(app.SubjectEditField.Value), ".tsv"], '');
            seegFile = strjoin(["./subj_", num2str(app.SubjectEditField.Value), "_ses_" num2str(app.SessionEditField.Value), ".mat"], '');
            videoSessFile = strjoin(["./sub-", num2str(app.SubjectEditField.Value), "_ses-", sprintf('%02d', app.SessionEditField.Value), ".mp4"], '');
            app.channelConfig = readtable(tsvFile, 'FileType', 'text', 'Delimiter', '\t','VariableNamingRule','preserve');
            seeg = load(seegFile, 'data', 'channelFlag');
            app.seegData = seeg.data;
            usedChannels = seeg.channelFlag;
            app.videoReader = VideoReader(videoSessFile);
            app.isPlaying = false;
            
            %TODO: Update list
            brainROI = {'Precentral', 'Postcentral', 'parietal', 'temporal', 'frontal', 'Insula', 'occipital', 'Amygdala'};
            idx = false(height(app.channelConfig), 1);
            all_nodes = [];
            for i = 1:length(brainROI)
                parent = uitreenode(app.Tree, 'Text', brainROI{i});
                all_nodes = [all_nodes, parent];
                all_channel_nodes = [];
                cortex_channel = contains(app.channelConfig.("Desikan-Killiany"), brainROI{i}, 'IgnoreCase', true);
                for j = 1:find(cortex_channel)
                    ch = app.channelConfig(j, 1);
                    child = uitreenode(parent, 'Text', string(ch{1, 1}));
                    all_nodes = [all_nodes, child];
                end
                idx = idx | cortex_channel;
            end
            app.Tree.CheckedNodes = all_nodes;
            expand(app.Tree)
            cortexChannels = app.channelConfig(idx, [1 9]);
            clear brainROI idx i

            % a.3 change A'1 to Ap1
            %TODO: check here!
            for i = 1:numel(usedChannels)
                usedChannels{i} = strrep(usedChannels{i}, '''', 'p');
            end
            usedChannels = usedChannels';
            
            % a.4 reverse find channels in eeg file
            for i = 1:height(cortexChannels)
                idx = find(strcmp(usedChannels, cortexChannels{i,1}));
                if ~isempty(idx)
                    app.channelIDs(i) = idx;
                else
                    app.channelIDs(i) = NaN;
                    % selectedChannels(i, :) = [];
                end
            end
            app.channelIDs = app.channelIDs';
            dd = isnan(app.channelIDs);
            cortexChannels(dd,:) = []; % contain brain region
            app.channelIDs(dd) = []; % contain channel number
            clear dd
            
            
            % b.1 get channel name and time
            for i = 1:length(app.channelIDs)
                idx = app.channelIDs(i);
                cns(i) = strcat(usedChannels(idx), ': ', cortexChannels{i,2});
                keys(i) = string(usedChannels(idx));
            end
            app.chName2ID = dictionary(keys', app.channelIDs);
            app.channelNames = cns;
            app.numCh = size(app.channelIDs, 1);
            app.totalDuration = size(app.seegData,1)/app.FrequencyfsEditField.Value;
            app.epochDuration = 30; % unit: second
            app.numEpoch = ceil(app.totalDuration / app.epochDuration);
            app.currentEpoch = 1;
            
            app.labels = repelem("N/A", app.numEpoch);
            app.LogLabel.Text = [...
                'Subject ' num2str(app.SubjectEditField.Value) newline...
                'Session ' num2str(app.SessionEditField.Value) newline newline...
                'Current epoch: ' newline...
                num2str(app.currentEpoch) ' / ' num2str(app.numEpoch) newline newline...
                'labeled: ' app.labels(app.currentEpoch)
            ];
            
            app.update_seeg(event)
            
            frame = readFrame(app.videoReader);
            imshow(frame, 'Parent', app.video);

        end

        % Button pushed function: ConffirmChannelsButton
        function update_seeg(app, event)
             %TODO: Update list
            for i = 1:length(app.channelIDs)
                idx = app.channelIDs(i);
                cns(i) = app.channelNames(idx);
            end

            startIdx = (app.currentEpoch - 1) * app.epochDuration * app.FrequencyfsEditField.Value + 1;
            endIdx = startIdx + app.epochDuration * app.FrequencyfsEditField.Value - 1;
            
            delta = max(max(app.seegData, [], 1) - min(app.seegData, [], 1));
            for i = 1:app.numCh
                % Offset each channel's signal to avoid overlap
                offset(i) = (i - 1) * delta;  % Adjust the offset as needed
            end
            offset = flip(offset);
            plot(app.seeg_wave, [startIdx:endIdx]/app.FrequencyfsEditField.Value, app.seegData(startIdx:endIdx, app.channelIDs) + offset);
            % Set y-axis labels to show channel numbers
            yticks(app.seeg_wave, offset);
            yticklabels(app.seeg_wave, cns);
            
            % Optimize the figure display
            axis(app.seeg_wave,'tight');
        end

        % Button pushed function: PlayButton
        function play_or_pause(app, event)
            if app.isPlaying
                pause();
                app.isPlaying = false;
            else
                app.isPlaying = true;
                while hasFrame(app.videoReader) && app.isPlaying
                    frame = readFrame(app.videoReader);
                    imshow(frame, 'Parent', app.video);
                    pause(1 / app.videoReader.FrameRate);
                end
            end
        end

        % Selection changed function: Tree
        function update_channels(app, event)
            selectedNodes = app.Tree.SelectedNodes;
            app.channelIDs = [];
            for i = 1:length(selectedNodes)
                selectedNodesText = selectedNodes(i).Text;
                app.channelIDs(i) = app.chName2ID(selectedNodesText);
            end
            app.numCh = size(app.channelIDs, 1);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.LeftPanel = uipanel(app.UIFigure);
            app.LabelListBoxLabel = uilabel(app.LeftPanel);
            app.LabelListBox = uilistbox(app.LeftPanel);
            app.LabelListBox.Items = {'N1', 'N2', 'N3', 'REM', 'Wake', 'Other'};
            app.LabelListBox.Value = 'N1';
            app.PreviousButton = uibutton(app.LeftPanel, 'push');
            app.PreviousButton.Text = 'Previous';
            app.NextButton = uibutton(app.LeftPanel, 'push');
            app.NextButton.Text = 'Next';
            app.SubjectEditFieldLabel = uilabel(app.LeftPanel);
            app.SubjectEditFieldLabel.Text = 'Subject';
            app.SubjectEditField = uieditfield(app.LeftPanel, 'numeric');
            app.SubjectEditField.Value = 21;
            app.SessionEditFieldLabel = uilabel(app.LeftPanel);
            app.SessionEditFieldLabel.Text = 'Session';
            app.SessionEditField = uieditfield(app.LeftPanel, 'numeric');
            app.SessionEditField.Value = 2;
            app.ConfirmButton = uibutton(app.LeftPanel, 'push');
            app.ConfirmButton.ButtonPushedFcn = createCallbackFcn(app, @load_data, true);
            app.ConfirmButton.Text = 'Confirm';
            app.LogLabel = uilabel(app.LeftPanel);
            app.LogLabel.Text = 'Log';
            app.CenterPanel = uipanel(app.UIFigure);
            app.seeg_wave = uiaxes(app.CenterPanel);
            title(app.seeg_wave, 'Title')
            xlabel(app.seeg_wave, 'X')
            ylabel(app.seeg_wave, 'Y')
            zlabel(app.seeg_wave, 'Z')
            app.IncreaseAmpButton = uibutton(app.CenterPanel, 'push');
            app.IncreaseAmpButton.Text = 'Increase Amp';
            app.DecreaseAmpButton = uibutton(app.CenterPanel, 'push');
            app.DecreaseAmpButton.Text = 'Decrease Amp';
            app.Tree = uitree(app.CenterPanel, 'checkbox');
            app.Tree.SelectionChangedFcn = createCallbackFcn(app, @update_channels, true);
            app.LabelListBoxLabel_2 = uilabel(app.CenterPanel);
            app.LabelListBoxLabel_2.Text = 'Channels';
            app.ConffirmChannelsButton = uibutton(app.CenterPanel, 'push');
            app.ConffirmChannelsButton.ButtonPushedFcn = createCallbackFcn(app, @update_seeg, true);
            app.ConffirmChannelsButton.Text = 'Conffirm Channels';
            app.FrequencyfsEditFieldLabel = uilabel(app.CenterPanel);
            app.FrequencyfsEditFieldLabel.Text = 'Frequency (fs)';
            app.FrequencyfsEditField = uieditfield(app.CenterPanel, 'numeric');
            app.FrequencyfsEditField.Value = 250;
            app.RightPanel = uipanel(app.UIFigure);
            app.video = uiaxes(app.RightPanel);
            title(app.video, 'Title')
            xlabel(app.video, 'X')
            ylabel(app.video, 'Y')
            zlabel(app.video, 'Z')
            app.Slider = uislider(app.RightPanel, 'range');
            app.PlayButton = uibutton(app.RightPanel, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @play_or_pause, true);
            app.PlayButton.Text = 'Play';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = annotator

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end